classdef gliomaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ToggleTumorMaskSwitch       matlab.ui.control.Switch
        ToggleTumorMaskSwitchLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ImageAcquisitionProtocolButtonGroup  matlab.ui.container.ButtonGroup
        T2Button                    matlab.ui.control.ToggleButton
        T2FLAIRButton               matlab.ui.control.ToggleButton
        T1GdButton                  matlab.ui.control.ToggleButton
        T1Button                    matlab.ui.control.ToggleButton
        Slider                      matlab.ui.control.Slider
        Image                       matlab.ui.control.Image
        BrowseButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        directory; % Directory path containing MRI files
        mri_image; % Image displaying the MRI
    end
    
    methods (Access = private)
        
        % Displays all .h5 files found in the directory object in the app
        % Calls function to load the MRI file for viewing
        % Will throw an error if no .h5 files are found
        function displayFiles(app)
            % Grab file list by creating a mask of dir entries that are files
            % and applying that mask to the directory contents
            % Then it transposes the array to be a column (using ')
            dir_object = dir(app.directory);
            files = {dir_object(~[dir_object.isdir]).name}';
            % Remove all files that are not .h5 files
            % https://stackoverflow.com/a/15604522
            files = files(~cellfun('isempty', regexpi(files, '\.h5$')));
            if isempty(files)
                errordlg("Error: No .h5 files found in directory");
            end
            % natsort function credit - Stephen Cobeldick
            % https://au.mathworks.com/matlabcentral/fileexchange/34464-customizable-natural-order-sort
            files = natsort(files);
            
            % Formatting for the table to display the files
            app.UITable.Data = files;
            app.UITable.ColumnEditable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selected_dir = uigetdir('', 'Select a Directory');
            % Only call displayFiles if selected_dir points to a new directory
            if selected_dir ~= 0 & ~strcmp(selected_dir, app.directory)
                app.directory = selected_dir;
                displayFiles(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Load an Image from File'};
            app.BrowseButton.Text = 'Browse...';
            app.Image = uiimage(app.UIFigure);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 154];
            app.Slider.MajorTicks = [0 154];
            app.Slider.MajorTickLabels = {'0', '154'};
            app.ImageAcquisitionProtocolButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageAcquisitionProtocolButtonGroup.Title = 'Image Acquisition Protocol';
            app.T1Button = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T1Button.Text = 'T1';
            app.T1Button.Value = true;
            app.T1GdButton = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T1GdButton.Text = 'T1Gd';
            app.T2FLAIRButton = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T2FLAIRButton.Text = 'T2-FLAIR';
            app.T2Button = uitogglebutton(app.ImageAcquisitionProtocolButtonGroup);
            app.T2Button.Text = 'T2';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ToggleTumorMaskSwitchLabel = uilabel(app.UIFigure);
            app.ToggleTumorMaskSwitchLabel.Text = 'Toggle Tumor Mask';
            app.ToggleTumorMaskSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gliomaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end